classdef App_automatische_Analyse_eines_Regelkreises_Kopie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        TabGroup_2                    matlab.ui.container.TabGroup
        ZeroPoleTab                   matlab.ui.container.Tab
        HTMLPZ                        matlab.ui.control.HTML
        PZButton                      matlab.ui.container.ButtonGroup
        SsButton                      matlab.ui.control.RadioButton
        TsButton                      matlab.ui.control.RadioButton
        KSsButton                     matlab.ui.control.RadioButton
        GSsButton                     matlab.ui.control.RadioButton
        LsButton                      matlab.ui.control.RadioButton
        KsButton                      matlab.ui.control.RadioButton
        GsButton                      matlab.ui.control.RadioButton
        pzplotUIAxes                  matlab.ui.control.UIAxes
        NyquistTab                    matlab.ui.container.Tab
        HTMLNyquistInfo               matlab.ui.control.HTML
        NyquistInfo                   matlab.ui.control.UIAxes
        nyquist_UIAxes2               matlab.ui.control.UIAxes
        BodeTab                       matlab.ui.container.Tab
        Bode_UIAxes                   matlab.ui.control.UIAxes
        BandwidthTab                  matlab.ui.container.Tab
        Bandwidth_UIAxes              matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        ResponseTab                   matlab.ui.container.Tab
        HTMLkeyparamTable             matlab.ui.control.HTML
        ButtonGroup                   matlab.ui.container.ButtonGroup
        r_d12Button                   matlab.ui.control.RadioButton
        SetpointButton                matlab.ui.control.RadioButton
        StepButton                    matlab.ui.control.RadioButton
        Response_UIAxes               matlab.ui.control.UIAxes
        StationaryValuesTab           matlab.ui.container.Tab
        HTMLkeyparamTable_2           matlab.ui.control.HTML
        JumpLabel                     matlab.ui.control.Label
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        SetpointandDisturbanceButton  matlab.ui.control.RadioButton
        OutputdisturbanceButton       matlab.ui.control.RadioButton
        SetButton                     matlab.ui.control.RadioButton
        Controllanderrorplo           matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        d_2_HTML                      matlab.ui.control.HTML
        d_1_HTML                      matlab.ui.control.HTML
        r_HTML                        matlab.ui.control.HTML
        K_HTML                        matlab.ui.control.HTML
        G_HTML                        matlab.ui.control.HTML
        HtmlTable                     matlab.ui.control.HTML
        LoadButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        controlloopstabilityLabel     matlab.ui.control.Label
        AnalyzeButton                 matlab.ui.control.Button
        Updated2Button                matlab.ui.control.Button
        Updated1Button                matlab.ui.control.Button
        UpdaterButton                 matlab.ui.control.Button
        UpdateKButton                 matlab.ui.control.Button
        UpdateGButton                 matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        r_UIAxes                      matlab.ui.control.UIAxes
        K_UIAxes                      matlab.ui.control.UIAxes
        G_UIAxes                      matlab.ui.control.UIAxes
        d_2_UIAxes                    matlab.ui.control.UIAxes
        d_1_UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        G
        K
        r
        r_s
        d_1
        d_1_s
        d_2
        d_2_s
        T
        t
        r_num_sections
        r_start_time
        r_end_time
        d1_num_sections
        d2_num_sections
        timeDescription
        cansysjump
        S
        L
        GS
        KS
        char_eq
        N_G
        Z_G
        N_K
        Z_K
        G_poles
        G_zeroes
        K_poles
        K_zeroes
        L_poles
        L_zeroes
        T_poles
        T_zeroes
        S_poles
        S_zeroes
        GS_poles
        GS_zeroes
        KS_poles
        KS_zeroes
    end
    
    methods (Access = private)


function displayTransferFunctionInApp(app, G, label)
    % displayTransferFunctionInApp
    % ----------------------------
    % This function displays the transfer function G as a LaTeX-formatted expression.
    % It extracts the numerator and denominator coefficients, converts them to LaTeX
    % strings, and then combines them into a fraction format.
    %
    % The resulting LaTeX string is displayed in the UIAxes component named 
    % [label '_UIAxes'] using the LaTeX interpreter.
    %
    % Inputs:
    %   app   - The app object containing the UI components.
    %   G     - The transfer function.
    %   label - A string specifying the label (e.g., 'G', 'K', etc.).
    
    % Extract numerator and denominator coefficients
    [num, den] = tfdata(G, 'v');
    
    % Convert the coefficients to LaTeX string representations
    num_str = poly2str(num, 's');
    den_str = poly2str(den, 's');
    
    % Replace scientific notation for LaTeX
    num_str = regexprep(num_str, 'e([-+]?\d+)', '\\cdot10^{\$1}');
    den_str = regexprep(den_str, 'e([-+]?\d+)', '\\cdot10^{\$1}');
    
    % Create the LaTeX fraction
    formulaLaTeX = ['$$' label '(s) = \frac{' num_str '}{' den_str '}$$'];
    
    % Determine the target UIAxes component (e.g., app.G_UIAxes)
    uiAxesName = [label '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);
    cla(uiAxesHandle);
    
    % Display the formula using the LaTeX interpreter
    text(uiAxesHandle, 0.5, 0.5, formulaLaTeX, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end

        
function displayPlaceholder(app, label, variable)

    % Placeholder formula to be displayed
    placeholderFormula = ['$$' label '(' variable ') = ???$$'];
    
    uiAxesName = [label '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);

    % Display the placeholder formula in the app using LaTeX interpreter
    text(uiAxesHandle, 0.5, 0.5, placeholderFormula, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end
        
        
function displayTimeFunction(app, target_value, num_sections, label, start_time, end_time)
    % displayTimeFunction
    % -------------------
    % This function displays the time-domain function as a LaTeX-formatted expression.
    % For a continuous function (num_sections == 1), if target_value is a function handle,
    % the function is evaluated symbolically using syms t and converted to a LaTeX string.
    % For a piecewise function (num_sections > 1), a piecewise definition is constructed using
    % \left\{ and an array environment.
    %
    % The resulting LaTeX string is displayed in the UIAxes component named 
    % [label '_UIAxes'].
    
    syms t;
    
    if num_sections == 1
        % Continuous case
        if isa(target_value, 'function_handle')
            timeFormula = target_value(t);
            latexTimeStr = latex(timeFormula);
        else
            latexTimeStr = num2str(target_value);
        end
        combinedFormula = ['$$' label '(t) = ', latexTimeStr, '$$'];
    else
        % Piecewise case: use \left\{ and an array
        n = num_sections;
        latexTimeStr = ['\left\{ \begin{array}{ll}'];
        for i = 1:n
            if isa(target_value{i}, 'function_handle')
                timePart = target_value{i}(t);
                latexTimePart = latex(timePart);
            else
                error('All elements in target_value must be function handles.');
            end
            % Add a small vertical space (using [5pt]) between rows if desired.
            latexTimeStr = [latexTimeStr, ' ', latexTimePart, ' & ', num2str(start_time(i)), ' \leq t \leq ', num2str(end_time(i)), ' \\[5pt] '];
        end
        latexTimeStr = [latexTimeStr, '\end{array}\right.'];
        combinedFormula = ['$$' label '(t) = ' latexTimeStr '$$'];
    end
    
    % Get the target UIAxes component (assumed to be named [label '_UIAxes'])
    uiAxesName = [label, '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);
    cla(uiAxesHandle);
    
    % Display the formula using the LaTeX interpreter
    text(uiAxesHandle, 0.5, 0.5, combinedFormula, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end


function char_eq = get_characteristic_equation(app, G, K)
    % Extract numerator and denominator polynomials
    [num_G, den_G] = tfdata(G, 'v');
    [num_K, den_K] = tfdata(K, 'v');

    app.N_G = num_G;
    app.Z_G = den_G;
    app.N_K = num_K;
    app.Z_K = den_K;

    % Create symbolic variables
    syms s;
    N_g = poly2sym(den_G, s);
    Z_g = poly2sym(num_G, s);
    N_k = poly2sym(den_K, s);
    Z_k = poly2sym(num_K, s);

    % Calculate the characteristic equation
    char_eq = expand(N_g * N_k + Z_g * Z_k);


    % Convert to LaTeX string
    latex_str = ['$', latex(char_eq), ' = 0$'];


    % Display in the specified axes
    %text(app.CharForm_UIAxes, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment','center');
end


function latex_string = generate_latex_string(app, system, system_name)
    % Calculate poles and zeroes
    poles = pole(system);
    zeroes = zero(system);
    
    % Generate LaTeX string
    latex_string = ['$' system_name '(s) \Rightarrow \, '];
    
    % Append zeroes to the string
    for i = 1:length(zeroes)
        latex_string = [latex_string, ' x_{z', num2str(i), '} = ', num2str(zeroes(i)), ', \, \ \ '];
    end
    
    % Append poles to the string
    for i = 1:length(poles)
        latex_string = [latex_string, ' x_{p', num2str(i), '} = ', num2str(poles(i)), ', \, \ \ '];
    end
    
    % Remove last comma and space, and close the LaTeX string
    latex_string = [latex_string(1:end-8), '$'];
    
    % Display LaTeX string in UIAxes
    uiAxesName = ['poles_and_zeroes_' system_name '_UIAxes'];
    uiAxesHandle = app.(uiAxesName); 
    cla(uiAxesHandle); % Clear existing content
    text(uiAxesHandle, 0.5, 0.5, latex_string, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end


function checkAndDisplayUnstableCancellation(app)

    
    % Check for unstable pole-zero cancellation
    isUnstableCancellation1 = checkUnstablePoleZeroCancellation(app.Z_G, app.N_K);
    isUnstableCancellation2 = checkUnstablePoleZeroCancellation(app.Z_K, app.N_G);
    
    % Determine the text message
    if isUnstableCancellation1 && isUnstableCancellation2
        message = 'Both systems have unstable pole-zero cancellations!';
    elseif isUnstableCancellation1 || isUnstableCancellation2
        message = 'One of the systems has an unstable pole-zero cancellation!';
    else
        message = 'No unstable pole-zero cancellations detected.';
    end
    
    % Display the message in the label
    app.pazcancel_label.Text = message;
end


function displayTransferFunctionInfo(app, tf_sys, label)
    % Set up the axis for displaying the results
    uiAxesName = [label '_InfoUIAxes'];
    uiAxesHandle = app.(uiAxesName);
    cla(uiAxesHandle); % Clear existing plots
    
    % Determine stability and type
    stability = routh_hurwitz_tf(tf_sys);
    if sternlyproper_tf(tf_sys)
        type = 'Sternly proper';
    elseif proper_tf(tf_sys)
        type = 'Proper';
    else
        type = 'Improper';
    end
    
    % Create LaTeX string
    latex_str = createLatexString(tf_sys, label, stability, type);
    
    % Display the LaTeX string in the axis
    text(uiAxesHandle, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end
        
function controlloopstable(app)
     if control_loop_stability(app.G,app.K) == true  
        message = 'The control loop is stable';
    else
        message = 'The control loop is unstable';
     end
    % Display the message in the label
    app.controlloopstabilityLabel.Text = message;
            
        end
        

function plot_TS_bandwidth(app, T, S)
    % plot_TS_bandwidth
    % -----------------
    % This function plots the transfer functions T and S and displays the bandwidth
    % of T. Instead of drawing a -3 dB line and a vertical bandwidth line, a marker is
    % placed on the T(s) curve at the frequency corresponding to the bandwidth.
    % The bandwidth frequency is also shown in the legend.
    %
    % Inputs:
    %   app - The app object containing the UI components.
    %   T   - The transfer function T.
    %   S   - The transfer function S.
    
    % Calculate the bandwidth of T (frequency at which T's magnitude drops by 3 dB)
    bandwidth_T = bandwidth(T);
    
    % Compute frequency response for T
    [magT, ~, omega] = bode(T);
    magT = squeeze(magT);
    omega = squeeze(omega);
    
    % Compute frequency response for S using the same frequency vector
    [magS, ~] = bode(S, omega);
    magS = squeeze(magS);
    
    % Convert magnitudes to decibels
    magT_dB = 20 * log10(magT);
    magS_dB = 20 * log10(magS);
    
    % Clear the UIAxes before plotting
    cla(app.Bandwidth_UIAxes);
    
    % Plot the magnitude response of T in blue
    semilogx(app.Bandwidth_UIAxes, omega, magT_dB, 'b', 'LineWidth', 2);
    hold(app.Bandwidth_UIAxes, 'on');
    
    % Plot the magnitude response of S in red
    semilogx(app.Bandwidth_UIAxes, omega, magS_dB, 'r', 'LineWidth', 2);
    
    % Find the index in omega closest to the bandwidth frequency
    [~, idx] = min(abs(omega - bandwidth_T));
    % Get the magnitude (in dB) at the bandwidth frequency for T(s)
    bwMag_dB = magT_dB(idx);
    
    % Plot a marker (black circle with green fill) at the bandwidth frequency on T(s)
    semilogx(app.Bandwidth_UIAxes, bandwidth_T, bwMag_dB, 'ko', 'MarkerSize', 8, 'MarkerFaceColor', 'g');
    
    % Update the legend to include the bandwidth frequency information
    legend(app.Bandwidth_UIAxes, 'T(s)', 'S(s)', sprintf('Bandwidth T: %.2f rad/s', bandwidth_T), 'Location', 'Best');
    
    grid(app.Bandwidth_UIAxes, 'on');
    hold(app.Bandwidth_UIAxes, 'off');
end


function plot_bode_margins(app, L)
    % Check if the input is a valid transfer function
    if ~isa(L, 'tf')
        error('Input must be a transfer function object');
    end
    
    % Calculate margins
    [Gm, Pm, Wcg, Wcp] = margin(L);
    
    % Convert gain margin to dB
    Gm_dB = 20 * log10(Gm);
    
    % Set up the axis for displaying the Bode plot
    uiAxesHandle = app.Bode_UIAxes;
    cla(uiAxesHandle); % Clear existing plots
    
    % Plot the Bode diagram using bodeplot with options to show margins
    bode(uiAxesHandle, L);
    %setoptions(h,  'Grid', 'on', );
    
    % Add annotations within the axis
    annotationStr = {
        ['Gain Margin: ', num2str(Gm_dB, '%.2f'), ' dB'], ...
        ['Phase Margin: ', num2str(Pm, '%.2f'), '°'], ...
        ['Gain Crossover Frequency: ', num2str(Wcg, '%.2f'), ' rad/s'], ...
        ['Phase Crossover Frequency: ', num2str(Wcp, '%.2f'), ' rad/s']
    };
    
    % Display the annotation within the axis
    text(uiAxesHandle, 'Units', 'normalized', 'Position', [0.1, 0.1], ...
        'String', annotationStr, 'FontSize', 12, 'BackgroundColor', 'white', ...
        'EdgeColor', 'black', 'Margin', 5);
end

        
function render_latex_table(app)

G_table = createLatexStringfortransfer(app.G, 'G'); 
K_table = createLatexStringfortransfer(app.K, 'K'); 
L_table = createLatexStringfortransfer(app.L, 'L'); 
T_table = createLatexStringfortransfer(app.T, 'T'); 
S_table = createLatexStringfortransfer(app.S, 'S'); 
GS_table = createLatexStringfortransfer(app.GS, 'GS'); 
KS_table = createLatexStringfortransfer(app.KS, 'KS'); 

[G_stable, G_proper, G_sternly] = check_tf_conditions(app.G);
[K_stable, K_proper, K_sternly] = check_tf_conditions(app.K);
[L_stable, L_proper, L_sternly] = check_tf_conditions(app.L);
[T_stable, T_proper, T_sternly] = check_tf_conditions(app.T);
[S_stable, S_proper, S_sternly] = check_tf_conditions(app.S);
[GS_stable, GS_proper, GS_sternly] = check_tf_conditions(app.GS);
[KS_stable, KS_proper, KS_sternly] = check_tf_conditions(app.KS);


    % Create a LaTeX table
tableStr = ['$$\begin{array}{|c|c|c|c|} \hline \mbox{Transferfunction} &\mbox{Stable} & \mbox{Proper} & \mbox{Strictly Proper} \\ \hline \mbox{' G_table '} &' G_stable '&' G_proper '&' G_sternly '\\ \hline \mbox{' K_table '} &' K_stable '&' K_proper '&' K_sternly '\\ \hline \mbox{' L_table '} &' L_stable '&' L_proper '&' L_sternly '\\ \hline \mbox{' T_table '} &' T_stable '&' T_proper '&' T_sternly '\\ \hline \mbox{' S_table '} &' S_stable '&' S_proper '&' S_sternly '\\ \hline \mbox{' GS_table '} &' GS_stable '&' GS_proper '&' GS_sternly '\\ \hline \mbox{' KS_table '} &' KS_stable '&' KS_proper '&' KS_sternly '\\ \hline \end{array}$$'];


    % Use the existing axis for rendering LaTeX
    uiAxesHandle = app.testaxis;
    cla(uiAxesHandle); % Clear previous contents
    
    % Adjust the axis properties to better fit the table
    axis(uiAxesHandle, 'off');

text(uiAxesHandle, 0.5, 0.5, tableStr, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');

end

function render_html_table(app)
    % render_html_table
    % -----------------
    % This function dynamically creates an HTML table displaying the transfer
    % functions and their properties, and shows it in a UIHTML component.
    %
    % The data is automatically obtained from the app properties (e.g., app.G, app.K, etc.).
    % The string representations of the transfer functions are generated using
    % createHtmlStringfortransfer, and the properties are determined using check_tf_conditions.
    
    % Generate the string representations of the transfer functions
    G_table  = createHtmlStringfortransfer(app.G, 'G');
    K_table  = createHtmlStringfortransfer(app.K, 'K');
    L_table  = createHtmlStringfortransfer(app.L, 'L');
    T_table  = createHtmlStringfortransfer(app.T, 'T');
    S_table  = createHtmlStringfortransfer(app.S, 'S');
    GS_table = createHtmlStringfortransfer(app.GS, 'GS');
    KS_table = createHtmlStringfortransfer(app.KS, 'KS');
    
    % Determine the properties of the transfer functions
    [G_stable, G_proper, G_sternly]   = check_tf_conditions(app.G);
    [K_stable, K_proper, K_sternly]   = check_tf_conditions(app.K);
    [L_stable, L_proper, L_sternly]   = check_tf_conditions(app.L);
    [T_stable, T_proper, T_sternly]   = check_tf_conditions(app.T);
    [S_stable, S_proper, S_sternly]   = check_tf_conditions(app.S);
    [GS_stable, GS_proper, GS_sternly] = check_tf_conditions(app.GS);
    [KS_stable, KS_proper, KS_sternly] = check_tf_conditions(app.KS);
    
    % Create the HTML content dynamically using sprintf.
    % Boolean values are converted to HTML symbols (checkmark or cross)
    % using the local helper function boolToSymbol.
    htmlContent = sprintf(['<html>'...
        '<head>'...
        '<style>'...
        'table { border-collapse: collapse; width: 100%%; }'...
        'th, td { border: 1px solid black; padding: 8px; text-align: center; font-size: 16px; }'...
        'th { background-color: #f2f2f2; }'...
        '</style>'...
        '</head>'...
        '<body>'...
        '<table>'...
        '<tr><th>Transfer Function</th><th>Stable</th><th>Proper</th><th>Strictly Proper</th></tr>'...
        '<tr><td>%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td>%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td>%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td>%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td>%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td>%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td>%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '</table>'...
        '</body>'...
        '</html>'], ...
        G_table,  boolToSymbol(G_stable),  boolToSymbol(G_proper),  boolToSymbol(G_sternly), ...
        K_table,  boolToSymbol(K_stable),  boolToSymbol(K_proper),  boolToSymbol(K_sternly), ...
        L_table,  boolToSymbol(L_stable),  boolToSymbol(L_proper),  boolToSymbol(L_sternly), ...
        T_table,  boolToSymbol(T_stable),  boolToSymbol(T_proper),  boolToSymbol(T_sternly), ...
        S_table,  boolToSymbol(S_stable),  boolToSymbol(S_proper),  boolToSymbol(S_sternly), ...
        GS_table, boolToSymbol(GS_stable), boolToSymbol(GS_proper), boolToSymbol(GS_sternly), ...
        KS_table, boolToSymbol(KS_stable), boolToSymbol(KS_proper), boolToSymbol(KS_sternly));
    
    % Check if the UIHTML component already exists.
    % The specified parent (here: app.Panel) is used.
    try
        app.HtmlTable.HTMLSource = htmlContent;
    catch
        % Create a new UIHTML component as a child of the Panel
        app.HtmlTable = uihtml(app.Panel, 'HTMLSource', htmlContent);
        % Set the position relative to app.Panel (x, y, width, height)
        app.HtmlTable.Position = [10 10 app.Panel.Position(3)-20 app.Panel.Position(4)-20];
    end
end


function plotnyquist(app)
    % plotnyquist
    % -----------
    % This function plots the Nyquist diagram for the transfer function L using
    % the nyquistSiso function. It then calculates key parameters for the Nyquist
    % analysis (number of unstable poles m₀, n₀, and delta phi), and displays the
    % analysis information in an HTML table on the UIHTML component app.HTMLNyquistInfo.
    % The table now shows the fixed values "m = 180°" and "n = 90°" in the same rows
    % as m₀ and n₀.
    %
    % The Nyquist plot is drawn in app.nyquist_UIAxes2.
    
    % Plot the Nyquist diagram using nyquistSiso
    [lReal, lImag, ~] = nyquistSiso(app.L);
    plot(app.nyquist_UIAxes2, lReal, lImag, 'b', 'LineWidth', 2);
    grid(app.nyquist_UIAxes2, 'on');
    hold(app.nyquist_UIAxes2, 'on');
    
    % Mark the starting point, ending point, and the critical point (-1, 0)
    plot(app.nyquist_UIAxes2, lReal(1), lImag(1), 'oc', 'LineWidth', 2);
    plot(app.nyquist_UIAxes2, lReal(end), lImag(end), 'xc', 'LineWidth', 2);
    plot(app.nyquist_UIAxes2, -1, 0, '+r', 'LineWidth', 2);
    xlabel(app.nyquist_UIAxes2, 'Real Axis');
    ylabel(app.nyquist_UIAxes2, 'Imaginary Axis');
    title(app.nyquist_UIAxes2, 'Nyquist Diagram for \omega=0...+\infty, o=L(j0), x=L(j\infty), +=critical point');
    
    % Calculate poles, zeros, and delta phi using a custom function
    [m_0, n_0, deltaphi] = countPolesforNyquist(app.L);
    
    % Check for unstable pole-zero cancellation between G(s) and K(s)
    if checkUnstablePoleZeroCancellation(app.G, app.K)
        pzcancel = 'with';
    else
        pzcancel = 'without';
    end
    
    % Check if L(s) is proper
    if ~proper_tf(app.L)
        lprop = 'not';
    else
        lprop = '';
    end
    
    % Convert numerical values to strings
    m_0_str = num2str(m_0);
    n_0_str = num2str(n_0);
    deltaphi_str = num2str(deltaphi);
    
    % Create an HTML table string that displays the Nyquist analysis information.
    % The rows for m and n now include the fixed values "m = 180°" and "n = 90°" along with m₀ and n₀.
    htmlTableStr = [...
        '<html><head><style>', ...
            'table { border-collapse: collapse; width: 100%; }', ...
            'th, td { border: 1px solid black; padding: 8px; text-align: center; font-size: 16px; }', ...
            'th { background-color: #f2f2f2; }', ...
        '</style></head><body>', ...
            '<table>', ...
                '<tr><th colspan="1">Information for Nyquist Analysis</th></tr>', ...
                '<tr><td>G(s) and K(s) ', pzcancel, ' pole/zero cancellation</td></tr>', ...
                '<tr><td>L(s) is ', lprop, ' proper</td></tr>', ...
                '<tr><td>m = 180°; m_0 = ', m_0_str, '</td></tr>', ...
                '<tr><td>n = 90°; n_0 = ', n_0_str, '</td></tr>', ...
                '<tr><td>&Delta;&phi; = ', deltaphi_str, '</td></tr>', ...
            '</table>', ...
        '</body></html>'];
    
    % Assign the HTML string to the UIHTML component app.HTMLNyquistInfo.
    app.HTMLNyquistInfo.HTMLSource = htmlTableStr;
    
    hold(app.nyquist_UIAxes2, 'off');
end


function calculateAndDisplayResponse(app, selectedButton)
    app.Response_UIAxes.Visible = 'on'; 
    
    % Check if the time vector is set, if not, open the set time window
    if isempty(app.t)
        app.t = create_linear_time_vector(app.r_num_sections);
        return; % Exit the function to wait for the time input
    end
    
    % Generate the input vector based on the function r
    input_function_values = generate_Function_Values_Version2(app.r);

    dis_1 = generate_Function_Values_Version2(app.d_1);
    dis_2 = generate_Function_Values_Version2(app.d_2);
    

    % different if-cases depending on which button is selected by the user

    if selectedButton == app.StepButton
        % Calculate and display Step response
        % avoid using  '[y, t_out] = step(app.T)' because it changes dimensions of y
        % using lsim instead and keeping the needed vector length 

        % Define the system (app.T)
        sys = app.T; 

        % Generate the step input signal for the existing time vector
        u = ones(length(app.t), 1); % Step input

        % Simulate the system response using lsim
        [y, t_out] = lsim(sys, u, app.t);

        responseTitle = 'System Step Response';
        cla(app.Response_UIAxes);
        plot(app.Response_UIAxes, t_out, y, 'DisplayName', 'System Response');

        displayKeyParameters(app, y)

    elseif selectedButton == app.SetpointButton
        % Calculate and display response to the custom function r
        disp('input:')
        disp(length(input_function_values))
        disp('app.t:')
        disp(length(app.t))
        y = lsim(app.T, input_function_values, app.t);
        responseTitle = 'System Response to Setpoint r';
        cla(app.Response_UIAxes);
        plot(app.Response_UIAxes, app.t, y, 'DisplayName', 'System Response');
        
        % Plot the custom function r
        hold(app.Response_UIAxes, 'on');
        plot(app.Response_UIAxes, app.t, input_function_values, '--', 'DisplayName', 'Input Function r');
        hold(app.Response_UIAxes, 'off');

        displayKeyParameters(app, y)

    elseif selectedButton == app.r_d12Button
        % Define the unity feedback system
        closed_loop_system = feedback(app.L, 1);

        % Calculate the system response to the target value
        y_target = lsim(closed_loop_system, input_function_values, app.t);

        % Calculate the system response to disturbances
        H_d1 = feedback(1, app.L); % Transfer function for disturbance D1
        H_d2 = app.L * feedback(1, app.L); % Transfer function for disturbance D2

        [y_d1, ~] = lsim(H_d1, dis_1, app.t);
        [y_d2, ~] = lsim(H_d2, dis_2, app.t);


        % Total system response
        y_total = y_target + y_d1 + y_d2;

        error = input_function_values - y_total;
        
        responseTitle = 'System Response to Setpoint r and Disturbances d_1 and d_2';
        cla(app.Response_UIAxes);
        plot(app.Response_UIAxes, app.t, y_total, 'DisplayName', 'System Response');

        
        % Plot the custom function r
        hold(app.Response_UIAxes, 'on');
        plot(app.Response_UIAxes, app.t, y_d1, '--', 'DisplayName', 'Disturbance d_1');
        hold(app.Response_UIAxes, 'off');

        % Plot the custom function d_1
        hold(app.Response_UIAxes, 'on');
        plot(app.Response_UIAxes, app.t, y_d2, '--', 'DisplayName', 'Disturbance d_2');
        hold(app.Response_UIAxes, 'off');

        % Plot the custom function d_1
        hold(app.Response_UIAxes, 'on');
        plot(app.Response_UIAxes, app.t, error, '--', 'DisplayName', 'Error');
        hold(app.Response_UIAxes, 'off');

        displayKeyParameters(app, y_total)


    end
    
    % Set title and labels
    title(app.Response_UIAxes, responseTitle);
    xlabel(app.Response_UIAxes, 'Time (s)');
    ylabel(app.Response_UIAxes, 'Amplitude');
    
    % Enable grid
    grid(app.Response_UIAxes, 'on');
    
    % Show legend
    legend(app.Response_UIAxes, 'show');
end


function setTimeAndClose(app)

    app.t = create_linear_time_vector(app.r_num_sections); 

    % Generate LaTeX-compatible string
    latexStr = ['$\mbox{From ', num2str(startTime), ' to ', num2str(endTime), ' with ', num2str(stepSize), ' steps}$'];
    app.timeDescription = latexStr;
  
end
        
function displayKeyParameters(app, y)
    % displayKeyParameters
    % ----------------------
    % This function calculates the step response information of the system y using stepinfo.
    % It extracts key parameters and displays them in an HTML table on the UIHTML component
    % app.HTMLkeyparamTable. The overshoot value is appended with a "%" sign.
    %
    % Input:
    %   app - The app object containing the UI components.
    %   y   - The output response (or system) for which the step information is computed.
    
    % Generate a structure with step response information using stepinfo.
    info = stepinfo(y, app.t);
    
    % Extract individual data fields from the stepinfo structure.
    RiseTime = info.RiseTime;
    SettlingTime = info.SettlingTime;
    SettlingMin = info.SettlingMin;
    SettlingMax = info.SettlingMax;
    Overshoot = info.Overshoot;
    Undershoot = info.Undershoot;
    Peak = info.Peak;
    PeakTime = info.PeakTime;
    TransientTime = info.TransientTime;
    
    % Convert numerical values to strings.
    RiseTime_str = num2str(RiseTime);
    SettlingTime_str = num2str(SettlingTime);
    SettlingMin_str = num2str(SettlingMin);
    SettlingMax_str = num2str(SettlingMax);
    % Append a percentage sign for overshoot.
    Overshoot_str = [num2str(Overshoot), ' %'];
    Undershoot_str = num2str(Undershoot);
    Peak_str = num2str(Peak);
    PeakTime_str = num2str(PeakTime);
    TransientTime_str = num2str(TransientTime);
    
    % Create the HTML table string using embedded CSS for styling.
    htmlTableStr = [...
        '<html><head><style>', ...
            'table { border-collapse: collapse; width: 100%; font-size: 18px; }', ...
            'th, td { border: 1px solid black; padding: 8px; text-align: center; }', ...
            'th { background-color: #f2f2f2; }', ...
        '</style></head><body>', ...
            '<table>', ...
                '<tr><th colspan="2">Key Parameters of a Response</th></tr>', ...
                '<tr><td>Rise time t_r</td><td>', RiseTime_str, '</td></tr>', ...
                '<tr><td>Settling time (2% band) t_s</td><td>', SettlingTime_str, '</td></tr>', ...
                '<tr><td>SettlingMin</td><td>', SettlingMin_str, '</td></tr>', ...
                '<tr><td>SettlingMax</td><td>', SettlingMax_str, '</td></tr>', ...
                '<tr><td>Overshoot</td><td>', Overshoot_str, '</td></tr>', ...
                '<tr><td>Undershoot</td><td>', Undershoot_str, '</td></tr>', ...
                '<tr><td>Peak</td><td>', Peak_str, '</td></tr>', ...
                '<tr><td>PeakTime</td><td>', PeakTime_str, '</td></tr>', ...
                '<tr><td>TransientTime</td><td>', TransientTime_str, '</td></tr>', ...
            '</table>', ...
        '</body></html>'];
    
    % Assign the HTML table string to the UIHTML component for display.
    app.HTMLkeyparamTable.HTMLSource = htmlTableStr;
end

        
function calculateAndDisplayErrorandOutput(app, selectedButton)
    % CALCULATEANDDISPLAYERRORANDOUTPUT - Calculate and display system response and stationary values
    % This function primarily uses simulation to determine steady-state values
    %
    % Inputs:
    %   app            - The app object containing the UI components and system data
    %   selectedButton - Selected button indicating the type of response to calculate
    
    % Make the response plot visible
    app.Controllanderrorplo.Visible = 'on';
    
    % Ensure we have valid transfer functions before proceeding
    if ~isa(app.T, 'tf') || ~isa(app.S, 'tf') || ~isa(app.G, 'tf')
        uialert(app.UIFigure, 'Please define valid transfer functions G(s) and K(s) first.', 'Invalid System');
        return;
    end
    
    % Display transfer function information for debugging
    disp('Transfer Functions Used for Calculation:');
    disp('T(s) = '); disp(app.T);
    disp('S(s) = '); disp(app.S);
    disp('G(s) = '); disp(app.G);
    
    % Determine setpoint and disturbance values
    if isempty(app.r_s)
        % Default to 1 if not set
        app.r_s = 1;
    end
    
    if isempty(app.d_1_s)
        % Default to 0 if not set
        app.d_1_s = 0;
    end
    
    if isempty(app.d_2_s)
        % Default to 0 if not set
        app.d_2_s = 0;
    end
    
    disp(['Reference Input (r_s): ', num2str(app.r_s)]);
    disp(['Output Disturbance (d_1_s): ', num2str(app.d_1_s)]);
    disp(['Input Disturbance (d_2_s): ', num2str(app.d_2_s)]);
    
    % Check if the time vector is set; if not, create it
    if isempty(app.t)
        app.t = create_linear_time_vector(app.r_num_sections);
        if isempty(app.t)
            uialert(app.UIFigure, 'Unable to create time vector. Please set time parameters.', 'Error');
            return;
        end
    end
    
    % Generate input vectors from user-defined functions
    try
        input_r = generate_Function_Values_Version2(app.r);
        input_d1 = generate_Function_Values_Version2(app.d_1);
        input_d2 = [];  % Initialize, will be used if needed
        
        if ~isempty(app.d_2)
            input_d2 = generate_Function_Values_Version2(app.d_2);
        end
        
        if isempty(input_r) || isempty(input_d1)
            uialert(app.UIFigure, 'Error generating input values. Please check your input functions.', 'Error');
            return;
        end
    catch ME
        uialert(app.UIFigure, ['Error generating input values: ' ME.message], 'Error');
        return;
    end
    
    % Initialize inputs_constant structure
    inputs_constant = struct('reference', true, 'disturbance1', true, 'disturbance2', true);
    
    % Check if inputs are constant
    if iscell(app.r)
        inputs_constant.reference = false;
    end
    
    if iscell(app.d_1)
        inputs_constant.disturbance1 = false;
    end
    
    if iscell(app.d_2)
        inputs_constant.disturbance2 = false;
    end
    
    % Choose response based on selected button
    if selectedButton == app.SetButton
        % Setpoint only: r constant and disturbances = 0
        try
            % Run simulation
            y_TR = lsim(app.T, input_r, app.t);
            e_SR = lsim(app.S, input_r, app.t);
            
            % Plot the results
            responseTitle = 'Function if Setpoint = const and disturbances = 0';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, app.t, y_TR, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, app.t, e_SR, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_r, ':', 'DisplayName', 'Reference');
            hold(app.Controllanderrorplo, 'off');
            
            % Use simulation results directly for steady-state values
            % Use the last 10% of simulation data for a more reliable estimate
            if ~isempty(y_TR) && length(y_TR) > 10
                endpart = max(1, round(0.9*length(y_TR)));
                y_stat_TR = mean(y_TR(endpart:end));
                e_stat_SR = mean(e_SR(endpart:end));
            else
                y_stat_TR = y_TR(end);
                e_stat_SR = e_SR(end);
            end
            
            % Compute jumpability
            app.cansysjump = (abs(e_stat_SR) < 1e-3); % System is jumpable if error is very small
            
            % Display steady-state values
            displayStationaryValues(app, y_stat_TR, e_stat_SR, inputs_constant);
            
            % Show simulation endpoint values for verification
            if ~isempty(y_TR) && length(y_TR) > 1
                y_sim_final = y_TR(end);
                e_sim_final = e_SR(end);
                disp('Simulation Endpoint Results:');
                disp(['Final output: y = ' num2str(y_sim_final)]);
                disp(['Final error: e = ' num2str(e_sim_final)]);
                
                % Check if reference input is constant
                if ~inputs_constant.reference
                    disp('Warning: Reference input is not constant. Steady-state analysis may not be accurate.');
                    
                    % Check variation in the input signal
                    input_variation = max(input_r) - min(input_r);
                    if input_variation > 0.001
                        disp(['  Reference input varies by ' num2str(input_variation) ' across the simulation.']);
                    end
                end
            end
            
        catch ME
            uialert(app.UIFigure, ['Simulation error: ' ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
        
    elseif selectedButton == app.OutputdisturbanceButton
        % Output disturbance only: r = 0, d_1 constant
        try
            % Run simulation
            y_SD1 = lsim(-app.S, input_d1, app.t);  % Use app.S, not just S
            e_SD1 = lsim(app.S, input_d1, app.t);
            
            % Plot the results
            responseTitle = 'Function if Output disturbance = const and r = 0';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, app.t, y_SD1, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, app.t, e_SD1, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_d1, ':', 'DisplayName', 'Disturbance');
            hold(app.Controllanderrorplo, 'off');
            
            % Use simulation results directly for steady-state values
            % Use the last 10% of simulation data for a more reliable estimate
            if ~isempty(y_SD1) && length(y_SD1) > 10
                endpart = max(1, round(0.9*length(y_SD1)));
                y_stat_SD1 = mean(y_SD1(endpart:end));
                e_stat_SD1 = mean(e_SD1(endpart:end));
            else
                y_stat_SD1 = y_SD1(end);
                e_stat_SD1 = e_SD1(end);
            end
            
            % Compute jumpability
            app.cansysjump = (abs(e_stat_SD1) < 1e-3); % System is jumpable if error is very small
            
            % Display steady-state values
            displayStationaryValues(app, y_stat_SD1, e_stat_SD1, inputs_constant);
            
            % Show simulation endpoint values for verification
            if ~isempty(y_SD1) && length(y_SD1) > 1
                y_sim_final = y_SD1(end);
                e_sim_final = e_SD1(end);
                disp('Simulation Endpoint Results:');
                disp(['Final output: y = ' num2str(y_sim_final)]);
                disp(['Final error: e = ' num2str(e_sim_final)]);
                
                % Check if disturbance is constant
                if ~inputs_constant.disturbance1
                    disp('Warning: Output disturbance is not constant. Steady-state analysis may not be accurate.');
                    
                    % Check variation in the disturbance signal
                    disturb_variation = max(input_d1) - min(input_d1);
                    if disturb_variation > 0.001
                        disp(['  Output disturbance varies by ' num2str(disturb_variation) ' across the simulation.']);
                    end
                end
            end
            
        catch ME
            uialert(app.UIFigure, ['Simulation error: ' ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
        
    elseif selectedButton == app.SetpointandDisturbanceButton
        % Combined case: r and output disturbance
        try
            % Run simulation for combined response
            y_r = lsim(app.T, input_r, app.t);
            y_d = lsim(-app.S, input_d1, app.t);  % Use app.S, not just S
            y_total = y_r + y_d;  % Combined response
            
            e_r = lsim(app.S, input_r, app.t);
            e_d = lsim(app.S, input_d1, app.t);
            e_total = e_r + e_d;  % Combined error
            
            % Plot the results
            responseTitle = 'Function if Setpoint and Output disturbance = const';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, app.t, y_total, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, app.t, e_total, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_r, ':', 'DisplayName', 'Reference');
            plot(app.Controllanderrorplo, app.t, input_d1, '-.', 'DisplayName', 'Disturbance');
            hold(app.Controllanderrorplo, 'off');
            
            % Use simulation results directly for steady-state values
            % Use the last 10% of simulation data for a more reliable estimate
            if ~isempty(y_total) && length(y_total) > 10
                endpart = max(1, round(0.9*length(y_total)));
                y_stat_combined = mean(y_total(endpart:end));
                e_stat_combined = mean(e_total(endpart:end));
            else
                y_stat_combined = y_total(end);
                e_stat_combined = e_total(end);
            end
            
            % Compute jumpability
            app.cansysjump = (abs(e_stat_combined) < 1e-3); % System is jumpable if error is very small
            
            % Display simulation-based steady-state values
            displayStationaryValues(app, y_stat_combined, e_stat_combined, inputs_constant);
            
            % Show simulation endpoint values for verification
            if ~isempty(y_total) && length(y_total) > 1
                y_sim_final = y_total(end);
                e_sim_final = e_total(end);
                disp('Simulation Endpoint Results:');
                disp(['Final output: y = ' num2str(y_sim_final)]);
                disp(['Final error: e = ' num2str(e_sim_final)]);
                
                % Check if inputs are constant
                if ~inputs_constant.reference || ~inputs_constant.disturbance1
                    disp('Warning: One or more inputs are not constant. Steady-state analysis may not be accurate.');
                    
                    if ~inputs_constant.reference
                        input_variation = max(input_r) - min(input_r);
                        if input_variation > 0.001
                            disp(['  Reference input varies by ' num2str(input_variation) ' across the simulation.']);
                        end
                    end
                    
                    if ~inputs_constant.disturbance1
                        disturb_variation = max(input_d1) - min(input_d1);
                        if disturb_variation > 0.001
                            disp(['  Output disturbance varies by ' num2str(disturb_variation) ' across the simulation.']);
                        end
                    end
                end
            end
            
        catch ME
            uialert(app.UIFigure, ['Simulation error: ' ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
    end
    
    % Set plot title, labels, grid, and legend
    title(app.Controllanderrorplo, responseTitle);
    xlabel(app.Controllanderrorplo, 'Time (s)');
    ylabel(app.Controllanderrorplo, 'Amplitude');
    grid(app.Controllanderrorplo, 'on');
    legend(app.Controllanderrorplo, 'show');
end


function displayStationaryValues(app, y, e, inputs_constant)
    % DISPLAYSTATIONARYVALUES - Display steady-state values in HTML table
    %
    % This function displays the steady-state values in an HTML table and shows
    % a message about whether the control loop is "jumpable".
    %
    % Inputs:
    %   app            - The app object containing the UI components
    %   y              - Steady-state output value
    %   e              - Steady-state error value
    %   inputs_constant - Structure with flags indicating if inputs are constant
    
    % Define threshold for considering values as effectively zero
    threshold = 1e-6;
    
    % Handle inputs_constant
    warning_msg = '';
    if nargin >= 4 && isstruct(inputs_constant)
        if ~inputs_constant.reference
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Reference input is not constant.</td></tr>'];
        end
        if ~inputs_constant.disturbance1
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Output disturbance is not constant.</td></tr>'];
        end
        if ~inputs_constant.disturbance2
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Input disturbance is not constant.</td></tr>'];
        end
        
        if ~isempty(warning_msg)
            warning_msg = [warning_msg, '<tr><td class="warning">Steady-state values are based on simulation end points.</td></tr>'];
        end
    end
    
    % Format output value string - ensuring we display a value even if it's NaN
    if isnan(y)
        % Use 0 as a fallback for NaN values
        disp('Warning: Output value is NaN, using 0 instead for display');
        y = 0;
        y_str = 'y<sub>&infin;</sub> = 0 (estimate)';
    elseif abs(y) < threshold
        % If y is very small, display as zero
        y_str = 'y<sub>&infin;</sub> = 0';
    else
        % Format y with 4 significant digits
        y_str = ['y<sub>&infin;</sub> = ', num2str(y, '%.4g')];
    end
    
    % Format error value string - ensuring we display a value even if it's NaN
    if isnan(e)
        % Use 0 as a fallback for NaN values
        disp('Warning: Error value is NaN, using 0 instead for display');
        e = 0;
        e_str = 'e<sub>&infin;</sub> = 0 (estimate)';
    elseif abs(e) < threshold
        % If e is very small, display as zero
        e_str = 'e<sub>&infin;</sub> = 0';
    else
        % Format e with 4 significant digits
        e_str = ['e<sub>&infin;</sub> = ', num2str(e, '%.4g')];
    end
    
    % Set jumpability message
    if app.cansysjump == 1
        jumpMessage = 'The control loop is Jumpable (Zero steady-state error for step input)';
    else
        jumpMessage = 'The control loop is not Jumpable (Has steady-state error for step input)';
    end
    app.JumpLabel.Text = jumpMessage;
    
    % Create HTML table string
    htmlTableStr = [...
        '<html><head><style>', ...
            'table { border-collapse: collapse; width: 100%; font-size: 18px; }', ...
            'th, td { border: 1px solid black; padding: 8px; text-align: center; }', ...
            'th { background-color: #f2f2f2; }', ...
            '.warning { color: #d9534f; font-weight: bold; }', ...
        '</style></head><body>', ...
            '<table>', ...
                '<tr><th>Stationary Values</th></tr>', ...
                '<tr><td>', y_str, '</td></tr>', ...
                '<tr><td>', e_str, '</td></tr>', ...
                '<tr><td><i>Based on simulation results</i></td></tr>', ...
                warning_msg, ...
            '</table>', ...
        '</body></html>'];
    
    % Display HTML table in the app
    app.HTMLkeyparamTable_2.HTMLSource = htmlTableStr;
    
    % Display additional diagnostic info in console
    disp(['Steady-state output: ', strrep(y_str, '<sub>&infin;</sub>', '(∞)')]);
    disp(['Steady-state error: ', strrep(e_str, '<sub>&infin;</sub>', '(∞)')]);
    disp(['System jumpability: ', app.JumpLabel.Text]);
end


function PZchange(app, selectedButton)
    % PZchange
    % ----------
    % Diese Funktion wird aufgerufen, wenn ein Button in der ButtonGroup ausgewählt wird.
    % Es wird direkt mit den bekannten App‑Buttons verglichen (z.B. app.GsButton, app.TsButton, etc.).
    % Je nach ausgewähltem Button wird das entsprechende Übertragungssystem ausgewählt,
    % die Pole und Nullstellen berechnet und in einer HTML-Tabelle in app.HTMLPZ angezeigt.
    % Zusätzlich wird ein Pole-Zero-Plot in app.pzplotUIAxes gezeichnet.
    
    if selectedButton == app.GsButton
        sys = app.G;
        varName = 'G';
    elseif selectedButton == app.TsButton
        sys = app.T;
        varName = 'T';
    elseif selectedButton == app.KsButton
        % Hier wird der Button für K (nicht KS) verwendet
        sys = app.K;
        varName = 'K';
    elseif selectedButton == app.KSsButton
        sys = app.KS;
        varName = 'KS';
    elseif selectedButton == app.GSsButton
        sys = app.GS;
        varName = 'GS';
    elseif selectedButton == app.LsButton
        sys = app.L;
        varName = 'L';
    elseif selectedButton == app.SsButton
        sys = app.S;
        varName = 'S';
    else
        error('Unbekannter Übertragungsfunktions-Button ausgewählt.');
    end

    % Debug-Ausgabe in der Konsole
    disp(['Selected Button: ', selectedButton.Tag]);
    disp(['VarName: ', varName]);

    % Berechne Pole und Nullstellen des Systems
    poles = pole(sys);
    zeroes = zero(sys);

    % Erstelle HTML-Strings für die Pole und Nullstellen mit Zeilenumbrüchen
    polesStr = '';
    for i = 1:length(poles)
        polesStr = [polesStr, 'x<sub>p', num2str(i), '</sub> = ', num2str(poles(i)), '<br>'];
    end
    zeroesStr = '';
    for j = 1:length(zeroes)
        zeroesStr = [zeroesStr, 'x<sub>z', num2str(j), '</sub> = ', num2str(zeroes(j)), '<br>'];
    end
    % Entferne das letzte <br> falls vorhanden
    if ~isempty(polesStr)
        polesStr = polesStr(1:end-4);
    end
    if ~isempty(zeroesStr)
        zeroesStr = zeroesStr(1:end-4);
    end

    % Erstelle den HTML-String für die Tabelle mit eingebettetem CSS
    htmlTableStr = [...
        '<html><head><style>', ...
            'table { border-collapse: collapse; width: 100%; }', ...
            'th, td { border: 1px solid black; padding: 5px; text-align: center; font-size: 16px; }', ...
        '</style></head><body>', ...
            '<table>', ...
                '<tr><th>Poles and Zeroes of the Transferfunction</th></tr>', ...
                '<tr><td>', polesStr, '</td></tr>', ...
                '<tr><td>', zeroesStr, '</td></tr>', ...
            '</table>', ...
        '</body></html>'];

    % Weise den HTML-String der UIHTML-Komponente app.HTMLPZ zu.
    app.HTMLPZ.HTMLSource = htmlTableStr;

    % Speichere Pole und Nullstellen als dynamische Variablen in der App
    app.([varName, '_poles']) = poles;
    app.([varName, '_zeroes']) = zeroes;

    % Erstelle einen Pole-Zero-Plot in der UIAxes (app.pzplotUIAxes)
    pzplot(app.pzplotUIAxes, sys);
end


    function build_G(app)

    % Get the button text as the function label
    label = 'G';

    % Get the transfer function from the user
    app.G = get_user_transfer_function();
    
    % If user didn't cancel, display the transfer function
    if ~isempty(app.G)
        displayTransferFunctionInApp(app, app.G, label);
    end

                
    end


    function build_K(app)

    % Get the button text as the function label
    label = 'K';

    app.K = get_user_controller();

    if ~isempty(app.K)
        displayTransferFunctionInApp(app, app.K, label);
            
    end
    end


  function build_r(app)
    % Get the button text as the function label
    label = 'r';

    [app.r, app.r_num_sections, app.r_start_time, app.r_end_time] = get_target_value_version2();
    
            
    % in case r was defined already and is changed
    % the time vector changes as well
    % hence t needs to be defined new:
    app.t = create_linear_time_vector(app.r_num_sections);
    
    if ~isempty(app.r)
        displayTimeFunction(app, app.r, app.r_num_sections, label, app.r_start_time, app.r_end_time);
    end
end


function saveAppVariables(app)
    % List of available variables in the app
    variables = {
        'G', 'K', 'r', 'r_s', 'd_1', 'd_1_s', 'd_2', 'd_2_s', 'T', 't', 'r_num_sections', ...
        'd1_num_sections', 'd2_num_sections', 'timeDescription', 'cansysjump', 'S', 'L', 'GS', 'KS', ...
        'char_eq', 'N_G', 'Z_G', 'N_K', 'Z_K', 'G_poles', 'G_zeroes', 'K_poles', 'K_zeroes', ...
        'L_poles', 'L_zeroes', 'T_poles', 'T_zeroes', 'S_poles', 'S_zeroes', 'GS_poles', 'GS_zeroes', ...
        'KS_poles', 'KS_zeroes'
    };

    % Initialize structure to save variables
    varsStruct = struct();

    % Assign values from app to the structure
    for i = 1:length(variables)
        varsStruct.(variables{i}) = app.(variables{i});
    end

    % Prompt user for filename
    filename = inputdlg('Enter filename (without extension):', 'Save Variables');
    if isempty(filename)
        msgbox('No filename entered. Operation cancelled.', 'Error', 'error');
        return;
    end
    filename = filename{1};
    
    % Remove invalid characters from filename
    invalidChars = {'<', '>', ':', '"', '/', '\', '|', '?', '*'};
    for i = 1:length(invalidChars)
        filename = strrep(filename, invalidChars{i}, '');
    end
    
    % Save the structure to a .mat file
    save([filename, '.mat'], '-struct', 'varsStruct');

    % Display confirmation message 
    msgbox('All done! Your variables are now saved!', 'Save Successful', 'help');
end

        
function success = loadAppVariables(app)
    % loadAppVariables lädt die benötigten Variablen aus einer .mat-Datei.
    % Falls keine Datei ausgewählt wird, wird die Funktion abgebrochen.
    %
    % \ac{Guard-Pattern} stellt sicher, dass nachfolgende Funktionen nur ausgeführt werden,
    % wenn eine Datei erfolgreich ausgewählt und geladen wurde \cite{DesignPatterns2020}.
    
    [file, path] = uigetfile('*.mat', 'Wählen Sie eine Datei aus');
    if isequal(file, 0)
        % Keine Datei ausgewählt – Abbruch der Funktion
        success = false;
        return;
    end
    
    % Erstellen des vollständigen Dateipfads und Laden der Daten
    fullFileName = fullfile(path, file);
    data = load(fullFileName);
    
    % Zuweisung der geladenen Variablen zu den App-Eigenschaften
    if isfield(data, 'G')
        app.G = data.G;
    else
        warning('Variable G wurde nicht gefunden.');
    end
    
    if isfield(data, 'K')
        app.K = data.K;
    else
        warning('Variable K wurde nicht gefunden.');
    end
    
    if isfield(data, 'r')
        app.r = data.r;
    else
        warning('Variable r wurde nicht gefunden.');
    end
    
    if isfield(data, 'r_num_sections')
        app.r_num_sections = data.r_num_sections;
    else
        warning('Variable r\_num\_sections wurde nicht gefunden.');
    end
    
    if isfield(data, 'd_1')
        app.d_1 = data.d_1;
    else
        warning('Variable d\_1 wurde nicht gefunden.');
    end
    
    if isfield(data, 'd1_num_sections')
        app.d1_num_sections = data.d1_num_sections;
    else
        warning('Variable d1\_num\_sections wurde nicht gefunden.');
    end
    
    if isfield(data, 'd_2')
        app.d_2 = data.d_2;
    else
        warning('Variable d\_2 wurde nicht gefunden.');
    end
    
    if isfield(data, 'd2_num_sections')
        app.d2_num_sections = data.d2_num_sections;
    else
        warning('Variable d2\_num\_sections wurde nicht gefunden.');
    end
    
    % Erfolgreiches Laden der Variablen – weitere Funktionen können ausgeführt werden
    success = true;
end


    end %end of private methods

    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            displayPlaceholder(app, 'G', 's')
            displayPlaceholder(app, 'K', 's')
            displayPlaceholder(app, 'r', 't')
            displayPlaceholder(app, 'd_1', 't')
            displayPlaceholder(app, 'd_2', 't')
            
           
        end

        % Button pushed function: UpdateGButton
        function UpdateGButtonPushed(app, event)

        build_G(app);

        end

        % Button pushed function: UpdateKButton
        function UpdateKButtonPushed(app, event)
            build_K(app);
        end

        % Button pushed function: UpdaterButton
        function UpdaterButtonPushed(app, event)
            build_r(app);
        end

        % Button pushed function: Updated1Button
        function Updated1ButtonPushed(app, event)
            label = 'd_1';
            
            [app.d_1, app.d1_num_sections] = get_disturbance_input_version2();
            
            displayTimeFunction(app, app.d_1, app.d1_num_sections, label, app.r_start_time, app.r_end_time)
        end

        % Button pushed function: Updated2Button
        function Updated2ButtonPushed(app, event)
            label = 'd_2';

            [app.d_2, app.d2_num_sections] = get_disturbance_input_version2();

            displayTimeFunction(app, app.d_2, app.d2_num_sections, label, app.r_start_time, app.r_end_time)
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            

            % when analyse button is pushsed
            % first check if all neccessary inputs are given
            % G, K, r(t) and t
            % if d1 and d2 are empty, they will be set to 0

            %check if G was not entered
                if isempty(app.G)
                build_G(app);
                end

            %check if K was not entered
                if isempty(app.K)
                build_K(app);
                end

            %check if r(t) was not entered
                if isempty(app.r)
                build_r(app);
                end

         % when analyse button is pushed
                 if isempty(app.t)
                     app.t = create_linear_time_vector(app.r_num_sections);
                 else
                    disp('Time vector already defined. Using cached values.');
                 end


            %check if d1(t) and d2(t) were not entered 
            % if this is the case, set both to zero
                if isempty(app.d_1)
                app.d_1 = @(t) 0 + 0*t;
                app.d1_num_sections = 1;
                label = 'd_1';
                displayTimeFunction(app, app.d_1, app.d1_num_sections, label)
                end    

                if isempty(app.d_2)
                app.d_2 = @(t) 0 + 0*t;
                app.d2_num_sections = 1;
                label = 'd_2';
                displayTimeFunction(app, app.d_1, app.d1_num_sections, label)
                end


            [app.T, app.S, app.L, app.GS, app.KS] = transferfunctions(app.G, app.K);

     
            render_html_table(app);
                
            app.PZButton.Visible = 'on';

            app.HtmlTable.Visible = 'on';

            app.HTMLPZ.Visible = 'on';

            app.SaveButton.Visible = 'on';

            app.ButtonGroup.Visible = 'on';

            app.ButtonGroup_2.Visible = 'on';

            app.nyquist_UIAxes2.Visible = "on";

            app.Bandwidth_UIAxes.Visible = "on";
            
            app.HTMLkeyparamTable_2.Visible = "on";

            app.HTMLNyquistInfo.Visible = "on";

            app.HTMLkeyparamTable.Visible = "on";

            plotnyquist(app);

            app.char_eq = get_characteristic_equation(app, app.G, app.K);

            controlloopstable(app);

            plot_TS_bandwidth(app, app.T, app.S);

            plot_bode_margins(app, app.L);

            calculateAndDisplayErrorandOutput(app, app.ButtonGroup_2.SelectedObject);

            calculateAndDisplayResponse(app, app.ButtonGroup.SelectedObject);

            PZchange(app, app.PZButton.SelectedObject);


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            selectedButton = app.ButtonGroup.SelectedObject;

            calculateAndDisplayResponse(app, selectedButton);
        

        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
    
            calculateAndDisplayErrorandOutput(app, selectedButton);

            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveAppVariables(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if loadAppVariables(app)
                displayTransferFunctionInApp(app, app.G, 'G');
                displayTransferFunctionInApp(app, app.K, 'K');
                displayTimeFunction(app, app.r, app.r_num_sections, 'r');
                displayTimeFunction(app, app.d_1, app.d1_num_sections, 'd_1');
                displayTimeFunction(app, app.d_2, app.d2_num_sections, 'd_2');
            else
                % Optional: Benutzer informieren, dass keine Datei ausgewählt wurde.
                uialert(app.UIFigure, 'Keine Datei ausgewählt!', 'Hinweis');
                end
        end

        % Selection changed function: PZButton
        function PZButtonSelectionChanged(app, event)
            selectedButton = app.PZButton.SelectedObject;

            PZchange(app, selectedButton)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Panel = uipanel(app.UIFigure);
            app.d_1_UIAxes = uiaxes(app.Panel);
            app.d_1_UIAxes.XTick = [];
            app.d_1_UIAxes.YTick = [];
            app.d_1_UIAxes.ZTick = [];
            app.d_1_UIAxes.Visible = 'off';
            app.d_2_UIAxes = uiaxes(app.Panel);
            app.d_2_UIAxes.XTick = [];
            app.d_2_UIAxes.YTick = [];
            app.d_2_UIAxes.ZTick = [];
            app.d_2_UIAxes.Visible = 'off';
            app.G_UIAxes = uiaxes(app.Panel);
            app.G_UIAxes.XTick = [];
            app.G_UIAxes.YTick = [];
            app.G_UIAxes.ZTick = [];
            app.G_UIAxes.Visible = 'off';
            app.K_UIAxes = uiaxes(app.Panel);
            app.K_UIAxes.XTick = [];
            app.K_UIAxes.YTick = [];
            app.K_UIAxes.ZTick = [];
            app.K_UIAxes.Visible = 'off';
            app.r_UIAxes = uiaxes(app.Panel);
            app.r_UIAxes.XTick = [];
            app.r_UIAxes.YTick = [];
            app.r_UIAxes.ZTick = [];
            app.r_UIAxes.Visible = 'off';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'App Bilder', 'Standardregelkreis.png');
            app.UpdateGButton = uibutton(app.Panel, 'push');
            app.UpdateGButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGButtonPushed, true);
            app.UpdateGButton.Text = 'Update G';
            app.UpdateKButton = uibutton(app.Panel, 'push');
            app.UpdateKButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateKButtonPushed, true);
            app.UpdateKButton.Text = 'Update K';
            app.UpdaterButton = uibutton(app.Panel, 'push');
            app.UpdaterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterButtonPushed, true);
            app.UpdaterButton.Text = 'Update r';
            app.Updated1Button = uibutton(app.Panel, 'push');
            app.Updated1Button.ButtonPushedFcn = createCallbackFcn(app, @Updated1ButtonPushed, true);
            app.Updated1Button.Text = 'Update d1';
            app.Updated2Button = uibutton(app.Panel, 'push');
            app.Updated2Button.ButtonPushedFcn = createCallbackFcn(app, @Updated2ButtonPushed, true);
            app.Updated2Button.Text = 'Update d2';
            app.AnalyzeButton = uibutton(app.Panel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.controlloopstabilityLabel = uilabel(app.Panel);
            app.controlloopstabilityLabel.Text = '';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.HtmlTable = uihtml(app.Panel);
            app.HtmlTable.Visible = 'off';
            app.G_HTML = uihtml(app.Panel);
            app.G_HTML.Visible = 'off';
            app.K_HTML = uihtml(app.Panel);
            app.K_HTML.Visible = 'off';
            app.r_HTML = uihtml(app.Panel);
            app.r_HTML.Visible = 'off';
            app.d_1_HTML = uihtml(app.Panel);
            app.d_1_HTML.Visible = 'off';
            app.d_2_HTML = uihtml(app.Panel);
            app.d_2_HTML.Visible = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResponseTab = uitab(app.TabGroup);
            app.ResponseTab.Title = 'Response';
            app.Response_UIAxes = uiaxes(app.ResponseTab);
            title(app.Response_UIAxes, 'Title')
            xlabel(app.Response_UIAxes, 'X')
            ylabel(app.Response_UIAxes, 'Y')
            zlabel(app.Response_UIAxes, 'Z')
            app.Response_UIAxes.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.ResponseTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.StepButton = uiradiobutton(app.ButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.SetpointButton = uiradiobutton(app.ButtonGroup);
            app.SetpointButton.Text = 'Setpoint';
            app.r_d12Button = uiradiobutton(app.ButtonGroup);
            app.r_d12Button.Text = 'Setpoint and Disturbances';
            app.HTMLkeyparamTable = uihtml(app.ResponseTab);
            app.HTMLkeyparamTable.Visible = 'off';
            app.StationaryValuesTab = uitab(app.TabGroup);
            app.StationaryValuesTab.Title = 'Stationary Values';
            app.Controllanderrorplo = uiaxes(app.StationaryValuesTab);
            title(app.Controllanderrorplo, 'Title')
            xlabel(app.Controllanderrorplo, 'X')
            ylabel(app.Controllanderrorplo, 'Y')
            zlabel(app.Controllanderrorplo, 'Z')
            app.Controllanderrorplo.Visible = 'off';
            app.ButtonGroup_2 = uibuttongroup(app.StationaryValuesTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Visible = 'off';
            app.SetButton = uiradiobutton(app.ButtonGroup_2);
            app.SetButton.Text = 'Setpoint';
            app.SetButton.Value = true;
            app.OutputdisturbanceButton = uiradiobutton(app.ButtonGroup_2);
            app.OutputdisturbanceButton.Text = 'Outputdisturbance';
            app.SetpointandDisturbanceButton = uiradiobutton(app.ButtonGroup_2);
            app.SetpointandDisturbanceButton.Text = 'Setpoint and Outputdisturbance';
            app.JumpLabel = uilabel(app.StationaryValuesTab);
            app.JumpLabel.Text = '';
            app.HTMLkeyparamTable_2 = uihtml(app.StationaryValuesTab);
            app.HTMLkeyparamTable_2.Visible = 'off';
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.ZeroPoleTab = uitab(app.TabGroup_2);
            app.ZeroPoleTab.Title = 'Zero/Pole';
            app.pzplotUIAxes = uiaxes(app.ZeroPoleTab);
            title(app.pzplotUIAxes, 'Title')
            xlabel(app.pzplotUIAxes, 'X')
            ylabel(app.pzplotUIAxes, 'Y')
            zlabel(app.pzplotUIAxes, 'Z')
            app.pzplotUIAxes.Visible = 'off';
            app.PZButton = uibuttongroup(app.ZeroPoleTab);
            app.PZButton.SelectionChangedFcn = createCallbackFcn(app, @PZButtonSelectionChanged, true);
            app.PZButton.Visible = 'off';
            app.GsButton = uiradiobutton(app.PZButton);
            app.GsButton.Text = 'G(s)';
            app.GsButton.Value = true;
            app.KsButton = uiradiobutton(app.PZButton);
            app.KsButton.Text = 'K(s)';
            app.LsButton = uiradiobutton(app.PZButton);
            app.LsButton.Text = 'L(s)';
            app.GSsButton = uiradiobutton(app.PZButton);
            app.GSsButton.Text = 'GS(s)';
            app.KSsButton = uiradiobutton(app.PZButton);
            app.KSsButton.Text = 'KS(s)';
            app.TsButton = uiradiobutton(app.PZButton);
            app.TsButton.Text = 'T(s)';
            app.SsButton = uiradiobutton(app.PZButton);
            app.SsButton.Text = 'S(s)';
            app.HTMLPZ = uihtml(app.ZeroPoleTab);
            app.HTMLPZ.Visible = 'off';
            app.NyquistTab = uitab(app.TabGroup_2);
            app.NyquistTab.Title = 'Nyquist';
            app.nyquist_UIAxes2 = uiaxes(app.NyquistTab);
            title(app.nyquist_UIAxes2, 'Nyquist Diagram for \omega=0...+\infty, o=L(j0), x=L(j\infty), +=critical point')
            xlabel(app.nyquist_UIAxes2, 'Real Axis')
            ylabel(app.nyquist_UIAxes2, 'Imaginary Axis')
            zlabel(app.nyquist_UIAxes2, 'Z')
            app.nyquist_UIAxes2.Visible = 'off';
            app.NyquistInfo = uiaxes(app.NyquistTab);
            xlabel(app.NyquistInfo, 'X')
            ylabel(app.NyquistInfo, 'Y')
            zlabel(app.NyquistInfo, 'Z')
            app.NyquistInfo.Visible = 'off';
            app.HTMLNyquistInfo = uihtml(app.NyquistTab);
            app.HTMLNyquistInfo.Visible = 'off';
            app.BodeTab = uitab(app.TabGroup_2);
            app.BodeTab.Title = 'Bode';
            app.Bode_UIAxes = uiaxes(app.BodeTab);
            title(app.Bode_UIAxes, 'Title')
            xlabel(app.Bode_UIAxes, 'X')
            ylabel(app.Bode_UIAxes, 'Y')
            zlabel(app.Bode_UIAxes, 'Z')
            app.Bode_UIAxes.Visible = 'off';
            app.BandwidthTab = uitab(app.TabGroup_2);
            app.BandwidthTab.Title = 'Bandwidth';
            app.Bandwidth_UIAxes = uiaxes(app.BandwidthTab);
            title(app.Bandwidth_UIAxes, 'Magnitude Response with Bandwidth')
            xlabel(app.Bandwidth_UIAxes, 'Frequency (rad/s)')
            ylabel(app.Bandwidth_UIAxes, 'Magnitude (dB)')
            zlabel(app.Bandwidth_UIAxes, 'Z')
            app.Bandwidth_UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_automatische_Analyse_eines_Regelkreises_Kopie

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end